classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DensityLabel                  matlab.ui.control.Label
        DensityEditField              matlab.ui.control.NumericEditField
        SubmitButton                  matlab.ui.control.StateButton
        ViscocidaddinmicaLabel        matlab.ui.control.Label
        ViscocidaddinmicaEditField    matlab.ui.control.NumericEditField
        DiametromtsEditFieldLabel     matlab.ui.control.Label
        DiametromtsEditField          matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel      matlab.ui.control.Label
        VelocitymsEditField           matlab.ui.control.NumericEditField
        RugosidadLabel                matlab.ui.control.Label
        RugosidadEditField            matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        FrictionfactorEditFieldLabel  matlab.ui.control.Label
        FrictionfactorEditField       matlab.ui.control.EditField
        ComportamientohidrulicoDropDownLabel  matlab.ui.control.Label
        ComportamientohidrulicoDropDown  matlab.ui.control.DropDown
        ErrorEditFieldLabel           matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.NumericEditField
        PipefrictionLabel             matlab.ui.control.Label
        InitialValueEditFieldLabel    matlab.ui.control.Label
        InitialValueEditField         matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        NumberofiterationsEditField   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: SubmitButton
        function SubmitButtonPushed(app, event)
            if(app.ComportamientohidrulicoDropDown.Value=="Flujo hicráulicamente semi rugoso")
                disp(app.ComportamientohidrulicoDropDown.Value);
                rho=app.DensityEditField.Value;
                mu=app.ViscocidaddinmicaEditField.Value;
                D=app.DiametromtsEditField.Value;
                V=app.VelocitymsEditField.Value;
                e=app.ViscocidaddinmicaEditField.Value;
                Re=rho*V*D/mu;
                syms x;
                f = 1/(sqrt(x))+(2*log10( (e/(3.7*D))+(2.51/(Re*sqrt(x)))));
                f1=(diff(f));
            end

            tol = app.ErrorEditField.Value;
            x=app.InitialValueEditField.Value;
            sw = 0;
            i=app.NumberofiterationsEditField.Value;
            iterations=0;
            while sw==0
                x1=x-(subs(f)/subs(f1));
                if (abs(x1-x)/x1)*100>tol
                    x=x1;
                    sw=0;
                elseif(iterations==i)
                        app.FrictionfactorEditField.Value ="Error retry with different values";
                        sw=1;
                else 
                    sw=1;
                end
                iterations=iterations+1;
            end
            disp(iterations);
            if i ~= iterations
                disp(vpa(x1));
                x1=vpa(x1);
                app.FrictionfactorEditField.Value =string(x1);
            end        
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DensityLabel = uilabel(app.UIFigure);
            app.DensityLabel.Text = 'Density (ρ)';
            app.DensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubmitButton = uibutton(app.UIFigure, 'state');
            app.SubmitButton.ValueChangedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ViscocidaddinmicaLabel = uilabel(app.UIFigure);
            app.ViscocidaddinmicaLabel.Text = 'Viscocidad dinámica(μ)';
            app.ViscocidaddinmicaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametromtsEditFieldLabel = uilabel(app.UIFigure);
            app.DiametromtsEditFieldLabel.Text = 'Diametro (mts)';
            app.DiametromtsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RugosidadLabel = uilabel(app.UIFigure);
            app.RugosidadLabel.Text = 'Rugosidad (ε)';
            app.RugosidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrictionfactorEditFieldLabel = uilabel(app.UIFigure);
            app.FrictionfactorEditFieldLabel.Text = 'Friction factor';
            app.FrictionfactorEditField = uieditfield(app.UIFigure, 'text');
            app.ComportamientohidrulicoDropDownLabel = uilabel(app.UIFigure);
            app.ComportamientohidrulicoDropDownLabel.Text = 'Comportamiento hidráulico';
            app.ComportamientohidrulicoDropDown = uidropdown(app.UIFigure);
            app.ComportamientohidrulicoDropDown.Items = {'Flujo hidráulicamente lisa', 'Flujo hicráulicamente semi rugoso', 'Flujo hidráulicamnte rugoso'};
            app.ComportamientohidrulicoDropDown.Value = 'Flujo hicráulicamente semi rugoso';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error %';
            app.ErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorEditField.Value = 1;
            app.PipefrictionLabel = uilabel(app.UIFigure);
            app.PipefrictionLabel.Text = 'Pipe friction';
            app.InitialValueEditFieldLabel = uilabel(app.UIFigure);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.InitialValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofiterationsEditField.Limits = [10 200];
            app.NumberofiterationsEditField.Value = 50;
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end